classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        xtDataTable3                 matlab.ui.control.Table
        xtDataTable2                 matlab.ui.control.Table
        xtDataTable1                 matlab.ui.control.Table
        SelectColumnListBoxLabel     matlab.ui.control.Label
        SelectColumnListBox          matlab.ui.control.ListBox
        TypeFrequencyEditField       matlab.ui.control.NumericEditField
        TypeFrequencyEditFieldLabel  matlab.ui.control.Label
        RawEditField                 matlab.ui.control.EditField
        RawEditFieldLabel            matlab.ui.control.Label
        TimesEditField               matlab.ui.control.EditField
        TimesEditFieldLabel          matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.EditField
        FrequencyEditFieldLabel      matlab.ui.control.Label
        SensorEditField              matlab.ui.control.EditField
        SensorEditFieldLabel         matlab.ui.control.Label
        ORLabel                      matlab.ui.control.Label
        ImportTimesButton            matlab.ui.control.Button
        NoCheckBoxFrequencyColumn    matlab.ui.control.CheckBox
        YesCheckBoxFrequencyColumn   matlab.ui.control.CheckBox
        PlotSelectedColumnsButton    matlab.ui.control.Button
        IsTimesdataonthefirstcolumnoftheCSVfileLabel  matlab.ui.control.Label
        TimesFrequencyLabel          matlab.ui.control.Label
        PlotAllButton                matlab.ui.control.Button
        DeleteRowButton              matlab.ui.control.Button
        AddtoTableButton             matlab.ui.control.Button
        ImportCSVButton              matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        xtDataLabel                  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = private)        
        %% Time Series
        xt_file_directory
        
        % This can include times as first column or not
        xt_table % Input Original Time Series Table (Table)
        xt_matrix_data % Input Original Time Series Matrix Data (Matrix)
        
        xt_class_data % (xtData Class)
        xt_data % Time Series Data (Matrix)
        xt_times % Times Data (Matrix)
        xt_frequency % Frequency (Might be an int)

        % Select Data
        xt_selected % Selected xt_data values depending on XT UITable and
                    % ListBox (Matrix)
        xt_selected_struct % Selected struct inside xtDataTable1
        

        %% Point Process
        pp_table % Point Process Table
        pp_selected % Selected Index inside PP Table

    end
    
    properties (Access = public)
        NewUITable % Description
        NewTab
    end
    
    methods (Access = private)
        % Functions to create data cells
        function calculateXtDataCell(app)
            num_column = width(app.xt_data);

            % TODO: Ask Trevor how a user might add new data so you know
            % how to change the parameter instead of forcing it by 1

            app.xt_class_data = SAT.xtDataHolder_new(1,num_column);
            app.xt_class_data = dataCell.constructors.getXtDataHolder(1, num_column);
            for i = 1 : num_column
                % Add raw data to xtDataCell (Add it as traspose)
                app.xt_class_data{1,1}(i).raw = app.xt_data(:,i)';

                % TODO: Does not work when directly fill in the envelope
                % Ask Trevor about that

                % Add envelope data to xtDataCell
                app.xt_class_data{1,1}(i).envelope = app.xt_data(:,i)';

                % Add sensor's name to xtDataCell
                app.xt_class_data{1,1}(i).sensor = app.UITable.ColumnName{i};
            end
        end

        %% Two functions used for displaying on UITables

        % Tables need to have only numerical values
        % Change doubles into table
        function double_table = doubleToTable(app, double_matrix)
            double_table = table();

            % Trapose the matrix if height == 1 for better displablity
            if height(double_matrix) == 1
                double_matrix = double_matrix';
            end

            for i = 1 : width(double_matrix)
                double_table = addvars(double_table, double_matrix(:,i));
            end 
        end


        % Change numerical table into doubles
        function double_matrix =  tableToDouble(app, double_table)
            % Allocate the matrix
            double_matrix = ones(height(double_table), width(double_table));
            for i = 1 : width(double_table)
                double_matrix(:,i) = double_table{:,i};
            end
        end


        % Change doubles data inside structure to char
        function new_struct = modifyToCharInStruct(app, old_struct)
            new_struct = old_struct;

            % Return the coloum names(cell) of the struct
            field_names = fieldnames(new_struct);
            
            % Loop thorught the columns
            for i = 1 : length(field_names)
                field_name = field_names{i};
                
                % Check if the column has doubles inside it
                if isa(new_struct(1).(field_name), 'double')
                    % Create a new struct for chars
                    char_struct = struct(); 
                    
                    % Loop through the rows of each column
                    for j = 1 : numel(new_struct)
                        field_value = new_struct(j).(field_name);

                        % Do not change the scalar double into char
                        if ~isempty(field_value) && numel(field_value) ~= 1
                            field_value_string = sprintf('%dx%d %s', ...
                                size(field_value, 1), size(field_value, 2), class(field_value));
                            char_struct(j).(field_name) = field_value_string;
                            new_struct(j).(field_name) = char_struct(j).(field_name);
                        elseif isempty(field_value)
                            field_value_string = '[]';
                            char_struct(j).(field_name) = field_value_string;
                            new_struct(j).(field_name) = char_struct(j).(field_name);
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportCSVButton
        function ImportCSVButtonPushed(app, event)
            % Get File Path
            % [file, path] = uigetfile('*.*');
            % app.xt_file_directory = [path file];
            app.xt_file_directory = "D:\PMK Files\Jobs\Research Cooridinator\UpdatedSDOAnalysis\SdoAnalysisToolkit\sat-master\emg_sample_data_w_times.txt";

            % Get the matrix data of time series
            app.xt_matrix_data = readmatrix(app.xt_file_directory);
            app.xt_table = readtable(app.xt_file_directory);

            % Reset the Yes/No button for time Question in first coloumn
            % Disable the graph and plotting
            app.YesCheckBoxFrequencyColumn.Value = 0;
            app.NoCheckBoxFrequencyColumn.Value = 0;
            if app.UITable.Enable == "on"
                app.UITable.ColumnName = {};
                app.UITable.Data = {};
            end
            app.UITable.Enable = "off";
            app.PlotAllButton.Enable = "off";
            app.PlotSelectedColumnsButton.Enable = "off";
            app.SelectColumnListBox.Enable = "off";           
            
        end

        % Button pushed function: AddtoTableButton
        function AddtoTableButtonPushed(app, event)

            sensor = string(app.SensorEditField.Value);
            %save("mydata.mat", "sensor");
            frequency = str2double(app.FrequencyEditField.Value);
            %save("mydata.mat", "frequency", "-append");
            time = str2double(app.TimesEditField.Value);
            %save("mydata.mat", "time", "-append");
            raw = str2double(app.RawEditField.Value);
            %save("mydata.mat", "raw", "-append");
            new_row = {sensor frequency time raw};
            save("mydata.mat", "new_row", "-append");
            app.UITable.Data = [app.UITable.Data; new_row];
            app.xt_table = app.UITable.Data;

            %% Debugging
            save("mydata.mat", "sensor", "frequency", "raw", "time", "new_row");
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            
            for i = 1:height(app.xt_selected)
                row_number = app.xt_selected(1);
                app.UITable.Data(row_number, :) = [];
                app.xt_selected(1,:) = [];
            end
            app.xt_table = app.UITable.Data;
        
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % Store every cell selected inside variable
            % if selected inside table, event.Indices returns an index
            % matrix (a matrix of row,colomn pairs)
            index_matrix = event.Indices;
            selected_column = unique(index_matrix(:,2));
            app.xt_selected = app.xt_data(:,selected_column(1):selected_column(end));
             
        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            plot(app.UIAxes, app.xt_data);
        end

        % Value changed function: YesCheckBoxFrequencyColumn
        function YesCheckBoxFrequencyColumnValueChanged(app, event)
            % Reset UITable
            app.UITable.Data = [];
            
            value = app.YesCheckBoxFrequencyColumn.Value;
            if value
                app.NoCheckBoxFrequencyColumn.Value = 0;
                app.UITable.Enable = "on";
                app.PlotAllButton.Enable = "on";
                app.PlotSelectedColumnsButton.Enable = "on";
                app.SelectColumnListBox.Enable = "on";

                app.ImportTimesButton.Visible = "off";
                app.TypeFrequencyEditField.Visible = "off";
                app.TypeFrequencyEditFieldLabel.Visible = "off";
                app.ORLabel.Visible = "off";
                
                % Storing time series data and times data
                app.xt_data = app.xt_matrix_data(:,2:end);
                app.xt_times = app.xt_matrix_data(:,1); 

                %% Display Table

                % For Display
                display_table = app.xt_table;
                display_table(:,1) = []; % Remove first column

                options = detectImportOptions(app.xt_file_directory);
                % Set column name accordingly
                if options.VariableNames(1) ~= "Var1"
                    app.UITable.ColumnName = display_table.Properties.VariableNames;
                else
                    default_column = ["Channel 1","Channel 2", ...
                    "Channel 3","Channel 4","Channel 5","Channel 6","Channel 7", ...
                    "Channel 8","Channel 9","Channel 10","Channel 11","Channel 12", ...
                    "Channel 13","Channel 14","Channel 15","Channel 16","Channel 17", ...
                    "Channel 18","Channel 19","Channel 20","Channel 21","Channel 22", ...
                    "Channel 23","Channel 24","Channel 25","Channel 26","Channel 27", ...
                    "Channel 28","Channel 29","Channel 30"];
                    app.UITable.ColumnName = default_column(1:width(app.xt_data));
                end

                % I am appending the data to the original table.
                app.UITable.Data = [app.UITable.Data; display_table];
                
                % Modify the column list box
                app.SelectColumnListBox.Items = app.UITable.ColumnName(1:width(app.xt_data));             

                % Calculation
                calculateXtDataCell(app);

                % Display data for xtDataCell class
                app.xtDataTable1.Visible = "on";
                xtClassData = cell2table(app.xt_class_data);
                app.xtDataTable1.Data = xtClassData;
            end            
        end

        % Value changed function: NoCheckBoxFrequencyColumn
        function NoCheckBoxFrequencyColumnValueChanged(app, event)
            % Reset UITable
            app.UITable.Data = [];
            
            value = app.NoCheckBoxFrequencyColumn.Value;
            if value
                app.YesCheckBoxFrequencyColumn.Value = 0;
                app.UITable.Enable = "on";
                app.PlotAllButton.Enable = "on";
                app.PlotSelectedColumnsButton.Enable = "on";
                app.SelectColumnListBox.Enable = "on";

                app.ImportTimesButton.Visible = "on";
                app.TypeFrequencyEditField.Visible = "on";
                app.TypeFrequencyEditFieldLabel.Visible = "on";
                app.ORLabel.Visible = "on";

                % Storing time series data and times data
                app.xt_data = app.xt_matrix_data; 

                %% Display Table

                % For Display
                display_table = app.xt_table;

                options = detectImportOptions(app.xt_file_directory);
                % Set column name accordingly
                if options.VariableNames(1) ~= "Var1"
                    app.UITable.ColumnName = display_table.Properties.VariableNames;
                else
                    default_column = ["Channel 1","Channel 2", ...
                    "Channel 3","Channel 4","Channel 5","Channel 6","Channel 7", ...
                    "Channel 8","Channel 9","Channel 10","Channel 11","Channel 12", ...
                    "Channel 13","Channel 14","Channel 15","Channel 16","Channel 17", ...
                    "Channel 18","Channel 19","Channel 20","Channel 21","Channel 22", ...
                    "Channel 23","Channel 24","Channel 25","Channel 26","Channel 27", ...
                    "Channel 28","Channel 29","Channel 30"];
                    app.UITable.ColumnName = default_column(1:width(app.xt_data));
                end

                % I am appending the data to the original table.
                app.UITable.Data = [app.UITable.Data; display_table]; 

                % Modify the column list box    
                app.SelectColumnListBox.Items = app.UITable.ColumnName(1:width(app.xt_data));

                % Calculation
                calculateXtDataCell(app);  
            
                % Add data for the class table
                xtClassData = cell2table(app.xt_class_data);
                app.xtDataTable1.Data = xtClassData;
                        
            end

        end

        % Button pushed function: PlotSelectedColumnsButton
        function PlotSelectedColumnsButtonPushed(app, event)
            plot(app.UIAxes, app.xt_selected);
        end

        % Value changed function: SelectColumnListBox
        function SelectColumnListBoxValueChanged(app, event)
            selected_value = app.SelectColumnListBox.Value;
            
            % It's necessary to copy the table data with correct column
            % variables since matlab changed the column name to default one
            table_data = app.UITable.Data;
            table_data.Properties.VariableNames = app.UITable.ColumnName;

            selected_matrix = ones(height(app.xt_data),length(selected_value)); % Allocate the matrix
            for i = 1 : length(selected_value)
                column = selected_value{i};
                selected_matrix(:,i) = table_data{:,column};
            end

            app.xt_selected = selected_matrix;
            
        end

        % Button pushed function: ImportTimesButton
        function ImportTimesButtonPushed(app, event)
            [file, path] = uigetfile('*.*');
            file_directory = [path file];
            times_matrix = readmatrix(file_directory);

            if width(times_matrix) > 1
                app.xt_times = times_matrix';
            else
                app.xt_times = times_matrix;
            end

            % Calculation

        end

        % Value changed function: TypeFrequencyEditField
        function TypeFrequencyEditFieldValueChanged(app, event)
            app.xt_frequency = app.TypeFrequencyEditField.Value;
            %% TODO: Ask Trevor how to calculate frequency

            % Calculation

        end

        % Double-clicked callback: xtDataTable1
        function xtDataTable1DoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;

            % Display on new table only when a cell is double-clicked
            if ~ (isempty(displayRow) || isempty(displayColumn))
                % First the table will return a cell so you have to select
                % the data again to get a struct
                app.xt_selected_struct = app.xtDataTable1.Data{app.xtDataTable1.Selection(1), app.xtDataTable1.Selection(2)}{1,1};

                % TODO: Fix the app inside function parameters below

                % Change the doubles to char for effiency and for displaying
                selected_new_struct = modifyToCharInStruct(app, app.xt_selected_struct);
                selected_cell_table = struct2table(selected_new_struct);

                % Display on table
                app.xtDataTable2.Visible = "on";
                app.xtDataTable2.Data = selected_cell_table;
                app.xtDataTable2.ColumnName = selected_cell_table.Properties.VariableNames;
            end
            
        end

        % Double-clicked callback: xtDataTable2
        function xtDataTable2DoubleClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;

            % Display on new table only when a cell is double-clicked
            if ~ (isempty(displayRow) || isempty(displayColumn))
                columns = fieldnames(app.xt_selected_struct);
                column = columns{displayColumn};
                data_to_display = app.xt_selected_struct(displayRow).(column);

                % Trapose the doubles for better display
                if height(data_to_display) == 1 && isa(data_to_display, 'double')
                    data_to_display = data_to_display';
                end

                % Display on table
                app.xtDataTable3.Visible = "on";
                app.xtDataTable3.Data = data_to_display;
                app.xtDataTable3.ColumnName = sprintf("%s(%d)",column, displayRow);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.xtDataLabel = uilabel(app.UIFigure);
            app.xtDataLabel.Text = 'xtData';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Enable = 'off';
            app.ImportCSVButton = uibutton(app.UIFigure, 'push');
            app.ImportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCSVButtonPushed, true);
            app.ImportCSVButton.Text = 'Import CSV';
            app.AddtoTableButton = uibutton(app.UIFigure, 'push');
            app.AddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableButtonPushed, true);
            app.AddtoTableButton.Text = 'Add to Table';
            app.DeleteRowButton = uibutton(app.UIFigure, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.PlotAllButton = uibutton(app.UIFigure, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Enable = 'off';
            app.PlotAllButton.Text = 'Plot All';
            app.TimesFrequencyLabel = uilabel(app.UIFigure);
            app.TimesFrequencyLabel.Text = 'Times/Frequency';
            app.IsTimesdataonthefirstcolumnoftheCSVfileLabel = uilabel(app.UIFigure);
            app.IsTimesdataonthefirstcolumnoftheCSVfileLabel.Text = 'Is Times data on the first column of the CSV file?';
            app.PlotSelectedColumnsButton = uibutton(app.UIFigure, 'push');
            app.PlotSelectedColumnsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedColumnsButtonPushed, true);
            app.PlotSelectedColumnsButton.Enable = 'off';
            app.PlotSelectedColumnsButton.Text = 'Plot Selected Columns';
            app.YesCheckBoxFrequencyColumn = uicheckbox(app.UIFigure);
            app.YesCheckBoxFrequencyColumn.ValueChangedFcn = createCallbackFcn(app, @YesCheckBoxFrequencyColumnValueChanged, true);
            app.YesCheckBoxFrequencyColumn.Text = 'Yes';
            app.NoCheckBoxFrequencyColumn = uicheckbox(app.UIFigure);
            app.NoCheckBoxFrequencyColumn.ValueChangedFcn = createCallbackFcn(app, @NoCheckBoxFrequencyColumnValueChanged, true);
            app.NoCheckBoxFrequencyColumn.Text = 'No';
            app.ImportTimesButton = uibutton(app.UIFigure, 'push');
            app.ImportTimesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTimesButtonPushed, true);
            app.ImportTimesButton.Visible = 'off';
            app.ImportTimesButton.Text = 'Import Times';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Visible = 'off';
            app.ORLabel.Text = 'OR';
            app.SensorEditFieldLabel = uilabel(app.UIFigure);
            app.SensorEditFieldLabel.Text = 'Sensor';
            app.SensorEditField = uieditfield(app.UIFigure, 'text');
            app.SensorEditField.InputType = 'alphanumerics';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencyEditField.InputType = 'alphanumerics';
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Times';
            app.TimesEditField = uieditfield(app.UIFigure, 'text');
            app.TimesEditField.InputType = 'alphanumerics';
            app.RawEditFieldLabel = uilabel(app.UIFigure);
            app.RawEditFieldLabel.Text = 'Raw';
            app.RawEditField = uieditfield(app.UIFigure, 'text');
            app.RawEditField.InputType = 'alphanumerics';
            app.TypeFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.TypeFrequencyEditFieldLabel.Visible = 'off';
            app.TypeFrequencyEditFieldLabel.Text = 'Type Frequency';
            app.TypeFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.TypeFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @TypeFrequencyEditFieldValueChanged, true);
            app.TypeFrequencyEditField.Visible = 'off';
            app.SelectColumnListBox = uilistbox(app.UIFigure);
            app.SelectColumnListBox.Items = {};
            app.SelectColumnListBox.Multiselect = 'on';
            app.SelectColumnListBox.ValueChangedFcn = createCallbackFcn(app, @SelectColumnListBoxValueChanged, true);
            app.SelectColumnListBox.Enable = 'off';
            app.SelectColumnListBox.Value = {};
            app.SelectColumnListBoxLabel = uilabel(app.UIFigure);
            app.SelectColumnListBoxLabel.Enable = 'off';
            app.SelectColumnListBoxLabel.Text = 'Select Column';
            app.xtDataTable1 = uitable(app.UIFigure);
            app.xtDataTable1.ColumnName = '';
            app.xtDataTable1.RowName = {};
            app.xtDataTable1.DoubleClickedFcn = createCallbackFcn(app, @xtDataTable1DoubleClicked, true);
            app.xtDataTable1.Visible = 'off';
            app.xtDataTable2 = uitable(app.UIFigure);
            app.xtDataTable2.ColumnName = '';
            app.xtDataTable2.RowName = {};
            app.xtDataTable2.DoubleClickedFcn = createCallbackFcn(app, @xtDataTable2DoubleClicked, true);
            app.xtDataTable2.Visible = 'off';
            app.xtDataTable3 = uitable(app.UIFigure);
            app.xtDataTable3.ColumnName = '';
            app.xtDataTable3.RowName = {};
            app.xtDataTable3.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end